classdef GUI_rettet2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectgenderButtonGroup         matlab.ui.container.ButtonGroup
        MaleButton                      matlab.ui.control.RadioButton
        FemaleButton                    matlab.ui.control.RadioButton
        StartmeasurementButton          matlab.ui.control.Button
        StopmeasurementButton           matlab.ui.control.Button
        ECGsignal                       matlab.ui.control.UIAxes
        SCGsignal                       matlab.ui.control.UIAxes
        CRFEditFieldLabel               matlab.ui.control.Label
        CRF_result_field                matlab.ui.control.NumericEditField
        StatusLamp                      matlab.ui.control.Lamp
        CRFEstimationLabel              matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EvaluateCRF                     matlab.ui.control.Label
        SelectagegroupButtonGroup       matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        Button_6                        matlab.ui.control.RadioButton
        RESTARTMATLABLabel              matlab.ui.control.Label
        SignalsremainingEditFieldLabel  matlab.ui.control.Label
        SignalsremainingEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CRF_result_field
        function CRF_result_fieldValueChanged(app, event)
  
        end

        % Button pushed function: StartmeasurementButton
        function StartmeasurementButtonPushed(app, event)
            app.StatusLamp.Color = 'g';
            app.StatusLamp.Enable = 'on';
            
          %  app.StopmeasurementButton.Enable = 'on';
            
            
Fs = 500;
number_of_signals = 15;
k = 1;
tid = (1:375)/Fs;

L_input = 375*2*2;     % The signal is 750 samples received as a double 
L_output = L_input;

s = serial('COM4');        % create Serial port object
s.baudrate = 115200;       % set baudrate 
s.InputBufferSize = L_input;     % set InputBufferSize to length of data
s.OutputBufferSize = L_output;    % set OutputBufferSize to length of data
try
    % Try to connect serial port object to device
    fopen(s)
catch
    % Display error message if connection fails and return
    disp(lasterr)
    return
end

% For loop readjusting and plotting signals
for i = 1:number_of_signals
    while s.BytesAvailable == 0     % Waiting for incoming data in serial port
    end
    data_available = s.BytesAvailable
    data(:,i) = fread(s,L_input/2,'int16');     % Reading the incoming data 
    
    signal_readjusted(1:(L_input/4),i) = (data(1:L_input/4,i)/10000);     % Readjusting the ECG signal
    signal_readjusted((L_input/4)+1:L_input/2,i) = (data((L_input/4)+1:L_input/2,i)/100000);     % Readjusting the SCG signal
    
    ECGplot = signal_readjusted(1:375,i);
    SCGplot = signal_readjusted(376:750,i);    
    
    % Defining 'hold on' function - signals are plotted in the same graph
    if i == 1
        hold(app.ECGsignal,'on');
        hold(app.SCGsignal,'on');
    end
    
    app.SignalsremainingEditField.Value = 15-i;     % Defining counter dispaying the remaining signals
    drawnow();
    plot(app.ECGsignal,tid,signal_readjusted(1:375,i));
    drawnow();
    plot(app.SCGsignal,tid,signal_readjusted(376:750,i));
    drawnow();
    
    
end
hold(app.ECGsignal,'off');     % Stopping 'hold on' function in the graph
hold(app.SCGsignal,'off');

while s.BytesAvailable == 0
end
AC_amp_ESP = fread(s,1,'int16');     % Reading serial port for incoming data
AC_amp = AC_amp_ESP/100000
fclose(s);     % Closing serial port for incoming data

      
CRF = 11.545*log(AC_amp) + 82.99;     % Computing CRF from predetermined model
app.CRF_result_field.Value = CRF;


       %%% Evaluation of CRF %%%
  
            if app.MaleButton.Value == true
                if app.Button.Value == true
                    if app.CRF_result_field.Value < 35
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 35 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 38.3
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 38.3 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 45.1
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 45.1 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 50.9
                        app.EvaluateCRF.Text = 'Good';
                    elseif 50.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 55.9
                        app.EvaluateCRF.Text = 'Excellent';
                    else 55.9 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                elseif app.Button_2.Value == true
                    if app.CRF_result_field.Value < 33
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 33 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 36.4
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 36.4 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 42.4
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 42.4 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 46.4
                        app.EvaluateCRF.Text = 'Good';
                    elseif 46.4 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 52.4
                        app.EvaluateCRF.Text = 'Excellent';
                    else 52.4 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                    
                elseif app.Button_3.Value == true
                    if app.CRF_result_field.Value < 31.5
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 31.5 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 35.4
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 35.4 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 40.9
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 40.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 44.9
                        app.EvaluateCRF.Text = 'Good';
                    elseif 44.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 49.4
                        app.EvaluateCRF.Text = 'Excellent';
                    else 49.4 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                    
                elseif app.Button_4.Value == true
                    if app.CRF_result_field.Value < 30.2
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 30.2 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 33.5
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 33.5 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 38.9
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 38.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 43.7
                        app.EvaluateCRF.Text = 'Good';
                    elseif 43.7 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 48.0
                        app.EvaluateCRF.Text = 'Excellent';
                    else 48 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                    
                elseif app.Button_5.Value == true
                    if app.CRF_result_field.Value < 26.1
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 26.1 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 30.9
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 30.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 35.7
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 35.7 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 40.9
                        app.EvaluateCRF.Text = 'Good';
                    elseif 40.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 45.3
                        app.EvaluateCRF.Text = 'Excellent';
                    else 45.3 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                    
                elseif app.Button_6.Value == true
                    if app.CRF_result_field.Value < 20.5
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 20.5 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 26.0
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 26.0 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 32.2
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 32.2 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 36.4
                        app.EvaluateCRF.Text = 'Good';
                    elseif 36.4 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 44.2
                        app.EvaluateCRF.Text = 'Excellent';
                    else 44.2 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                    
                end
            elseif app.FemaleButton.Value == true
                if app.Button.Value == true
                    if app.CRF_result_field.Value < 25
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 25 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 30.9
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 30.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 34.9
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 34.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 38.9
                        app.EvaluateCRF.Text = 'Good';
                    elseif 38.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 41.9
                        app.EvaluateCRF.Text = 'Excellent';
                    else 41.9 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                elseif app.Button_2.Value == true
                    if app.CRF_result_field.Value < 23.6
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 23.6 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 28.9
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 28.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 32.9
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 32.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 36.9
                        app.EvaluateCRF.Text = 'Good';
                    elseif 36.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 41
                        app.EvaluateCRF.Text = 'Excellent';
                    else 41 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                    
                elseif app.Button_3.Value == true
                    if app.CRF_result_field.Value < 22.8
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 22.8 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 26.9
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 26.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 31.4
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 31.4 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 35.6
                        app.EvaluateCRF.Text = 'Good';
                    elseif 35.6 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 40.0
                        app.EvaluateCRF.Text = 'Excellent';
                    else 40 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                    
                elseif app.Button_4.Value == true
                    if app.CRF_result_field.Value < 21
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 21 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 24.4
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 24.4 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 28.9
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 28.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 32.8
                        app.EvaluateCRF.Text = 'Good';
                    elseif 32.8 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 36.9
                        app.EvaluateCRF.Text = 'Excellent';
                    else 36.9 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                    
                elseif app.Button_5.Value == true
                    if app.CRF_result_field.Value < 20.2
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 20.2 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 22.7
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 22.7 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 26.9
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 26.9 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 31.4
                        app.EvaluateCRF.Text = 'Good';
                    elseif 31.4 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 35.7
                        app.EvaluateCRF.Text = 'Excellent';
                    else 35.7 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                    
                elseif app.Button_6.Value == true
                    if app.CRF_result_field.Value < 17.5
                        app.EvaluateCRF.Text = 'Very poor';
                    elseif 17.5 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 20.1
                        app.EvaluateCRF.Text = 'Poor';
                    elseif 20.1 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 24.4
                        app.EvaluateCRF.Text = 'Fair';
                    elseif 24.4 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 30.2
                        app.EvaluateCRF.Text = 'Good';
                    elseif 30.2 <= app.CRF_result_field.Value && app.CRF_result_field.Value < 31.4
                        app.EvaluateCRF.Text = 'Excellent';
                    else 31.4 <= app.CRF_result_field.Value;
                        app.EvaluateCRF.Text = 'Superior';
                    end
                end
                
            end


        end

        % Size changed function: SelectgenderButtonGroup
        function SelectgenderButtonGroupSizeChanged(app, event)
            position = app.SelectgenderButtonGroup.Position;


        end

        % Callback function
        function EnterageEditFieldValueChanged(app, event)
      
        end

        % Button pushed function: StopmeasurementButton
        function StopmeasurementButtonPushed(app, event)
app.RESTARTMATLABLabel.Visible = 'on';
              
s = serial('COM4');     % Create serial port object
fclose(s);     % Close serial port
      
        end

        % Callback function
        function EvaluateCRFValueChanged(app, event)


        end

        % Callback function
        function EvaluateCRFValueChanging(app, event)
            changingValue = event.Value;


        end

        % Selection changed function: SelectagegroupButtonGroup
        function SelectagegroupButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectagegroupButtonGroup.SelectedObject;
            app.StartmeasurementButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectgenderButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectgenderButtonGroup.Title = 'Select sex';
            app.SelectgenderButtonGroup.SizeChangedFcn = createCallbackFcn(app, @SelectgenderButtonGroupSizeChanged, true);
            app.MaleButton = uiradiobutton(app.SelectgenderButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.SelectgenderButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.StartmeasurementButton = uibutton(app.UIFigure, 'push');
            app.StartmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartmeasurementButtonPushed, true);
            app.StartmeasurementButton.Enable = 'off';
            app.StartmeasurementButton.Text = 'Start measurement';
            app.StopmeasurementButton = uibutton(app.UIFigure, 'push');
            app.StopmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StopmeasurementButtonPushed, true);
            app.StopmeasurementButton.Text = 'Stop measurement';
            app.ECGsignal = uiaxes(app.UIFigure);
            title(app.ECGsignal, 'ECG signal')
            xlabel(app.ECGsignal, 'seconds')
            ylabel(app.ECGsignal, 'volts')
            app.SCGsignal = uiaxes(app.UIFigure);
            title(app.SCGsignal, 'SCG signal')
            xlabel(app.SCGsignal, 'seconds')
            ylabel(app.SCGsignal, 'g')
            app.CRFEditFieldLabel = uilabel(app.UIFigure);
            app.CRFEditFieldLabel.Text = 'CRF';
            app.CRF_result_field = uieditfield(app.UIFigure, 'numeric');
            app.CRF_result_field.ValueChangedFcn = createCallbackFcn(app, @CRF_result_fieldValueChanged, true);
            app.StatusLamp = uilamp(app.UIFigure);
            app.StatusLamp.Enable = 'off';
            app.CRFEstimationLabel = uilabel(app.UIFigure);
            app.CRFEstimationLabel.Text = 'CRF Estimation';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EvaluateCRF = uilabel(app.UIFigure);
            app.EvaluateCRF.Text = ' ';
            app.SelectagegroupButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectagegroupButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectagegroupButtonGroupSelectionChanged, true);
            app.SelectagegroupButtonGroup.Title = 'Select age group';
            app.Button = uiradiobutton(app.SelectagegroupButtonGroup);
            app.Button.Text = {'13 - 19'; ''};
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SelectagegroupButtonGroup);
            app.Button_2.Text = {'20 - 29'; ''};
            app.Button_3 = uiradiobutton(app.SelectagegroupButtonGroup);
            app.Button_3.Text = {'30 - 39'; ''};
            app.Button_4 = uiradiobutton(app.SelectagegroupButtonGroup);
            app.Button_4.Text = {'40 - 49'; ''};
            app.Button_5 = uiradiobutton(app.SelectagegroupButtonGroup);
            app.Button_5.Text = '50 - 59';
            app.Button_6 = uiradiobutton(app.SelectagegroupButtonGroup);
            app.Button_6.Text = '60 +';
            app.RESTARTMATLABLabel = uilabel(app.UIFigure);
            app.RESTARTMATLABLabel.Visible = 'off';
            app.RESTARTMATLABLabel.Text = 'RESTART MATLAB!';
            app.SignalsremainingEditFieldLabel = uilabel(app.UIFigure);
            app.SignalsremainingEditFieldLabel.Text = 'Signals remaining';
            app.SignalsremainingEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_rettet2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end